classdef EIT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EITSystemv10UIFigure          matlab.ui.Figure
        file                          matlab.ui.container.Menu
        saveData                      matlab.ui.container.Menu
        savePicture                   matlab.ui.container.Menu
        set                           matlab.ui.container.Menu
        serialSet                     matlab.ui.container.Menu
        help                          matlab.ui.container.Menu
        about                         matlab.ui.container.Menu
        support                       matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        Imaging                       matlab.ui.container.Tab
        imageReconstructionUIAxes     matlab.ui.control.UIAxes
        parameterSetPanel             matlab.ui.container.Panel
        neleButtonGroup               matlab.ui.container.ButtonGroup
        ele8Button                    matlab.ui.control.RadioButton
        ele16Button                   matlab.ui.control.RadioButton
        stimButtonGroup               matlab.ui.container.ButtonGroup
        adjacentButton                matlab.ui.control.RadioButton
        oppositeButton                matlab.ui.control.RadioButton
        crossingButton                matlab.ui.control.RadioButton
        selfAdaptionButton            matlab.ui.control.RadioButton
        imageReconstructionModeButtonGroup  matlab.ui.container.ButtonGroup
        absoluteButton                matlab.ui.control.RadioButton
        differenceButton              matlab.ui.control.RadioButton
        imageReconstructionAlgorithmButtonGroup  matlab.ui.container.ButtonGroup
        Button_9                      matlab.ui.control.RadioButton
        Button_10                     matlab.ui.control.RadioButton
        Button_11                     matlab.ui.control.RadioButton
        StartPauseButton              matlab.ui.control.StateButton
        calibrationButton             matlab.ui.control.Button
        openSerialButton              matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        TextArea                      matlab.ui.control.TextArea
        logLabel                      matlab.ui.control.Label
        realTimeElectrodesVoltageUIAxes  matlab.ui.control.UIAxes
        Label                         matlab.ui.control.Label
        serialComDropDown             matlab.ui.control.DropDown
        msLabel                       matlab.ui.control.Label
        algorithmTimeConsumingEditField  matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        readyLamp                     matlab.ui.control.Lamp
        statusLamp                    matlab.ui.control.Lamp
        Testing                       matlab.ui.container.Tab
        testGetHomogeneousDataButton  matlab.ui.control.Button
        testGetDataOneceButton        matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        testStimElectrodesChooseDropDown  matlab.ui.control.DropDown
        DropDown_2Label               matlab.ui.control.Label
        testMeasureElectrodesChooseDropDown  matlab.ui.control.DropDown
        testStimingButton             matlab.ui.control.Button
        testMeasuringButton           matlab.ui.control.Button
        testDrawVoltageCurvesButton   matlab.ui.control.Button
        testLogTextArea               matlab.ui.control.TextArea
        e1EditFieldLabel              matlab.ui.control.Label
        e1EditField                   matlab.ui.control.NumericEditField
        e6EditFieldLabel              matlab.ui.control.Label
        e6EditField                   matlab.ui.control.NumericEditField
        e2EditFieldLabel              matlab.ui.control.Label
        e2EditField                   matlab.ui.control.NumericEditField
        e7EditFieldLabel              matlab.ui.control.Label
        e7EditField                   matlab.ui.control.NumericEditField
        e3EditFieldLabel              matlab.ui.control.Label
        e3EditField                   matlab.ui.control.NumericEditField
        e8EditFieldLabel              matlab.ui.control.Label
        e8EditField                   matlab.ui.control.NumericEditField
        e4EditFieldLabel              matlab.ui.control.Label
        e4EditField                   matlab.ui.control.NumericEditField
        e9EditFieldLabel              matlab.ui.control.Label
        e9EditField                   matlab.ui.control.NumericEditField
        e5EditFieldLabel              matlab.ui.control.Label
        e5EditField                   matlab.ui.control.NumericEditField
        e10EditFieldLabel             matlab.ui.control.Label
        e10EditField                  matlab.ui.control.NumericEditField
        e11EditFieldLabel             matlab.ui.control.Label
        e11EditField                  matlab.ui.control.NumericEditField
        e12EditFieldLabel             matlab.ui.control.Label
        e12EditField                  matlab.ui.control.NumericEditField
        e13EditFieldLabel             matlab.ui.control.Label
        e13EditField                  matlab.ui.control.NumericEditField
        e14EditFieldLabel             matlab.ui.control.Label
        e14EditField                  matlab.ui.control.NumericEditField
        e15EditFieldLabel             matlab.ui.control.Label
        e15EditField                  matlab.ui.control.NumericEditField
        e16EditFieldLabel             matlab.ui.control.Label
        e16EditField                  matlab.ui.control.NumericEditField
        simulationTab                 matlab.ui.container.Tab
        simulationButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        DeviceName='Arduino Uno'; % Description
        Serialx; % serial object
        RxBuff = zeros(1,1026) % Description
        RxCnt = 1; % Description
        SampleDataLength = 1026; % 帧头FE EE，16个电极，每个电极16组数据，共16*16=256个电压数据，每个电压数据用4字节表示，高字节在前，共256*4=1024字节数据
        SynCode = [hex2dec('12') hex2dec('34')]; % 握手信号，PC发送0x1234给单片机
        SystemReady = false; % 握手成功，系统就绪标志
        RxTotal = 0; % 串口接收统计
        TxTotal = 0; % 串口发送统计
        Enter2EidorsRootDir = false; % 已经进入了ediros根目录标志
        
        
        inv_model; % EIT逆问题模型
        vvRef=zeros(256,1); % voltages when no current inject,as reference
        vvAvg=zeros(256,1);% volatages at realtime
        
        cmd_getHomogeneousData_flag = false;%请求获取均匀介质数据标志
        cmd_continuousAcquisition_flag = false;%请求连续成像标志
        getOneFrame = false;%请求获取一帧数据标志
        getMeasureData = false;%请求获取一帧数据标志
        
        algorithmConsumedTime=0.000; % 算法消耗时间,ms
    end
    
    properties (Access = private)
    end

    methods (Access = public)
    
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            clc;            
            disp('wellcome to EIT system');
            
            try
                %查找所有串口设备，存在二维向量中，第一列为设备名，第二列为端口号
                device = IdentifySerialComs;
                
                app.logLabel.Text=['find ' device{1:end,1} ' @ ' 'COM' num2str(device{1:end,2})];
                
                %判断Arduino Uno设备是否存在
                r = ismember(app.DeviceName,device(1:end,1));
                
                if r == 0
                    app.logLabel.Text='no device';
                    msgbox('not find EIT','err','custom',imread('./res/err.png'));
                end
                
                %从中找到Arduino Uno设备的索引是多少
                [i,j] = find(strcmp(device,app.DeviceName));
                
                %访问元组元素，获得端口号码
                comnum=device{i,2};
                
                %拼接数字端口号为字符串形式
                com=['COM',num2str(comnum),' ',app.DeviceName];
                
                %将端口号字符串转换为元数组，并添加到下拉框
                app.serialComDropDown.Items = cellstr(com);
            catch
                               
            end
            
            app.realTimeElectrodesVoltageUIAxes.XTick=0:13:208;
            app.realTimeElectrodesVoltageUIAxes.XTickMode='manual';
            %app.realTimeElectrodesVoltageUIAxes.XTickLabel= {'e1','e2','e3','e4','e5','e6','e7','e8','e9','e10','e11','e12','e13','e14','e15','e16'};
            %app.realTimeElectrodesVoltageUIAxes.XTickLabelMode='manual';
            app.realTimeElectrodesVoltageUIAxes.XLim=[0 208];
            app.realTimeElectrodesVoltageUIAxes.XLimMode='manual';
            
            app.realTimeElectrodesVoltageUIAxes.YTick=0:10:100;
            app.realTimeElectrodesVoltageUIAxes.YTickMode='manual';
            %app.realTimeElectrodesVoltageUIAxes.YTickLabel= {'e1','e2','e3','e4','e5','e6','e7','e8','e9','e10','e11','e12','e13','e14','e15','e16'};
            %app.realTimeElectrodesVoltageUIAxes.YTickLabelMode='manual';
            app.realTimeElectrodesVoltageUIAxes.YLim=[0 100];
            app.realTimeElectrodesVoltageUIAxes.YLimMode='manual';
            
            app.realTimeElectrodesVoltageUIAxes.GridLineStyle=':';
            
            set(app.imageReconstructionUIAxes,'YTick', []);
            set(app.imageReconstructionUIAxes,'XTick', []);
            
            imshow('./res/bb.png','Parent',app.imageReconstructionUIAxes);
            
            app.StartPauseButton.Icon='./res/start.png';
            app.calibrationButton.Icon='./res/calibration.png';
            
            
            x = linspace(0,208,13*10);
            y = 30*sin(x/208*2*pi*4)+50;
            plot(app.realTimeElectrodesVoltageUIAxes,x,y,'LineStyle','-','Color','red','LineWidth',0.5,'Marker','o','MarkerSize',3,'MarkerFaceColor','red')
                              
            pause(3);
                               
            
            %%%%打开软件自动连接串口
            %字符‘打开串口’时将其打开
            if app.openSerialButton.Text == '打开串口'
                
                ports = strsplit(app.serialComDropDown.Value,' '); %获取当前下拉框选定的串口号
                
                port=ports{1,1};
                
                disp(port);
                
                if isempty(port) == true
                    msgbox('串口无效!');
                    return ;
                end
                
                app.Serialx = serial(port); %创建串口对象
                disp('try to open!!!!');
                disp(app.Serialx);
                if isvalid(app.Serialx) == 0
                    msgbox('串口已被占用!');
                    return;
                end
                
                app.Serialx.BytesAvailableFcnCount = 1026;% 设置中断响应（有1026个可用字节时，产生中断）
                app.Serialx.BytesAvailableFcnMode = 'byte';% 设置中断响应模式（有“byte”和“Terminator”两种模式可选，“byte”是达到一定字节数产生中断，“Terminator”可用作键盘某个按键事件来产生中断）
                
                app.Serialx.BytesAvailableFcn = {@instrcallback,app};%将整个app作为参数传到串口中断回调里面去
                
                % 设置串口数据最大读写时间，如果超过这个时间，仍读取不到数据，那么则终止读写
                % 注：串口的一些参数必须在打开串口之前进行设置，即在串口状态是“Close”的情况下来进行设置
                
                set(app.Serialx, 'TimeOut', 100);
                set(app.Serialx,'BaudRate',115200,'Parity','none','DataBits',8,'StopBits',1);
                set(app.Serialx,'InputBufferSize',1026);
%                 set(app.Serialx,'OutputBufferSize',1);
                
                %串口对象从内存或工作区删除了，需要把它恢复，否则串口对象打不开
                if isvalid(app.Serialx)==0
                    disp('serial object had been removed,this operation will recovery it!')
                    newobj = instrfind(port);
                    disp([newobj 'has been resumed!']);
                end
                
                if app.Serialx.Status=='closed'
                    try
                        fopen(app.Serialx); %尝试打开串口
                        disp('opened!!!!');
                        disp(app.Serialx);
                        app.Lamp.Color='green';%串口指示灯绿色
                        app.openSerialButton.Text = '关闭串口';
                        app.logLabel.Text = '打开串口成功，连接成功！';
                    catch
                        msgbox('打开串口失败，请重试！', 'Error','error');
                    end
                end
                
            end
            
        end

        % Value changed function: StartPauseButton
        function StartPauseButtonValueChanged(app, event)
            
            value = app.StartPauseButton.Value;
            
            if value==1
                
                app.cmd_continuousAcquisition_flag=true;
                
                fwrite(app.Serialx,hex2dec('53'),'uint8'); %'S'
                app.StartPauseButton.Icon='./res/pause.png';
                
            elseif value==0
                
                app.cmd_continuousAcquisition_flag=false;
                
                fwrite(app.Serialx,hex2dec('50'),'uint8'); %'P'               
                app.StartPauseButton.Icon='./res/start.png';     
                
            end
            
        end

        % Button pushed function: calibrationButton
        function calibrationButtonPushed(app, event)
            app.cmd_getHomogeneousData_flag=true;
            disp('******PC send cmd: need Homogeneous Data******');
            fwrite(app.Serialx,hex2dec('48'),'uint8');%'H'
            app.readyLamp.Color='green';
            
        end

        % Button pushed function: openSerialButton
        function openSerialButtonPushed(app, event)
            
            %字符‘打开串口’时将其打开
            if app.openSerialButton.Text == '打开串口'
                
                ports = strsplit(app.serialComDropDown.Value,' '); %获取当前下拉框选定的串口号
                
                port=ports{1,1};
                
                disp(port);
                
                if isempty(port) == true
                    msgbox('串口无效!');
                    return ;
                end
                
                app.Serialx = serial(port); %创建串口对象
                disp('try to open!!!!');
                disp(app.Serialx);
                if isvalid(app.Serialx) == 0
                    msgbox('串口已被占用!');
                    return;
                end
                
                app.Serialx.BytesAvailableFcnCount = 1026;% 设置中断响应（有1026个可用字节时，产生中断）
                app.Serialx.BytesAvailableFcnMode = 'byte';% 设置中断响应模式（有“byte”和“Terminator”两种模式可选，“byte”是达到一定字节数产生中断，“Terminator”可用作键盘某个按键事件来产生中断）
                
                app.Serialx.BytesAvailableFcn = {@instrcallback,app};%将整个app作为参数传到串口中断回调里面去
                
                % 设置串口数据最大读写时间，如果超过这个时间，仍读取不到数据，那么则终止读写
                % 注：串口的一些参数必须在打开串口之前进行设置，即在串口状态是“Close”的情况下来进行设置
                
                set(app.Serialx, 'TimeOut', 100);
                set(app.Serialx,'BaudRate',115200,'Parity','none','DataBits',8,'StopBits',1);
                set(app.Serialx,'InputBufferSize',1026);
%                 set(app.Serialx,'OutputBufferSize',1);
                
                %串口对象从内存或工作区删除了，需要把它恢复，否则串口对象打不开
                if isvalid(app.Serialx)==0
                    disp('serial object had been removed,this operation will recovery it!')
                    newobj = instrfind(port);
                    disp([newobj 'has been resumed!']);
                end
                
                if app.Serialx.Status=='closed'
                    try
                        fopen(app.Serialx); %尝试打开串口
                    catch
                        msgbox('打开串口失败，请重试！', 'Error','error');
                    end
                end
                disp('opened!!!!');
                disp(app.Serialx);
                app.Lamp.Color='green';%串口指示灯绿色
                app.openSerialButton.Text = '关闭串口';
                app.logLabel.Text = '打开串口成功，连接成功！';
                
                %字符‘关闭串口’时将其关闭
            elseif app.openSerialButton.Text == '关闭串口'
                
                while app.Serialx.TransferStatus~='idle'
                    ;
                end
                disp('try to close!!!!');
                disp(app.Serialx);
                if app.Serialx.Status=='open'
                    
                    try
                        fclose(app.Serialx); %尝试关闭串口
                    catch
                        msgbox('关闭串口失败，请重试！', 'Error','error');
                    end
                end
                
                disp('maybe closed!!!!');
                disp(app.Serialx);
                
                app.Lamp.Color='red';%串口指示灯黑色
                app.openSerialButton.Text = '打开串口';
                app.logLabel.Text = '串口已关闭，断开连接！';
                
            end
            
        end

        % Close request function: EITSystemv10UIFigure
        function EITSystemv10UIFigureCloseRequest(app, event)
            
            try
                fclose(app.Serialx); %尝试关闭串口
            catch
                
            end
            
            disp('maybe closed!!!!');
            
            delete(app.Serialx);%从内存中删除串行端口对象
            clear app.Serialx;%从MATLAB 工作区中删除串行端口对象
            
            disp('maybe delete!!!!');
            disp(isvalid(app.Serialx));
            
            delete(app);
            
        end

        % Button pushed function: testGetHomogeneousDataButton
        function testGetHomogeneousDataButtonPushed(app, event)
            app.testLogTextArea.Value=[datestr(now),'----','数据保存到根目录'];
        end

        % Menu selected function: about
        function aboutMenuSelected(app, event)
           msgbox('这是测试版本！v1.0 beta', '关于','help');
        end

        % Menu selected function: support
        function supportMenuSelected(app, event)
            
            msgbox('QQ:1303428517','技术支持','custom',imread('.\res\mywechat.jpg'));                       
            
        end

        % Button pushed function: simulationButton
        function simulationButtonPushed(app, event)
            eit_simulation;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EITSystemv10UIFigure = uifigure;
            app.EITSystemv10UIFigure.Name = 'EIT System v1.0';
            app.EITSystemv10UIFigure.CloseRequestFcn = createCallbackFcn(app, @EITSystemv10UIFigureCloseRequest, true);
            app.file = uimenu(app.EITSystemv10UIFigure);
            app.file.Text = '文件';
            app.saveData = uimenu(app.file);
            app.saveData.Text = '保存数据';
            app.savePicture = uimenu(app.file);
            app.savePicture.Text = '保存图片';
            app.set = uimenu(app.EITSystemv10UIFigure);
            app.set.Text = '设置';
            app.serialSet = uimenu(app.set);
            app.serialSet.Text = '串口参数';
            app.help = uimenu(app.EITSystemv10UIFigure);
            app.help.Text = '帮助';
            app.about = uimenu(app.help);
            app.about.MenuSelectedFcn = createCallbackFcn(app, @aboutMenuSelected, true);
            app.about.Text = '关于产品';
            app.support = uimenu(app.help);
            app.support.MenuSelectedFcn = createCallbackFcn(app, @supportMenuSelected, true);
            app.support.Text = '技术支持';
            app.TabGroup = uitabgroup(app.EITSystemv10UIFigure);
            app.Imaging = uitab(app.TabGroup);
            app.Imaging.Title = '成像';
            app.imageReconstructionUIAxes = uiaxes(app.Imaging);
            app.parameterSetPanel = uipanel(app.Imaging);
            app.parameterSetPanel.Title = '参数设置';
            app.neleButtonGroup = uibuttongroup(app.parameterSetPanel);
            app.neleButtonGroup.Title = '电极数目';
            app.ele8Button = uiradiobutton(app.neleButtonGroup);
            app.ele8Button.Text = '8';
            app.ele16Button = uiradiobutton(app.neleButtonGroup);
            app.ele16Button.Text = '16';
            app.ele16Button.Value = true;
            app.stimButtonGroup = uibuttongroup(app.parameterSetPanel);
            app.stimButtonGroup.Title = '电流注入方式';
            app.adjacentButton = uiradiobutton(app.stimButtonGroup);
            app.adjacentButton.Text = '相邻';
            app.adjacentButton.Value = true;
            app.oppositeButton = uiradiobutton(app.stimButtonGroup);
            app.oppositeButton.Text = '相对';
            app.crossingButton = uiradiobutton(app.stimButtonGroup);
            app.crossingButton.Text = '交叉';
            app.selfAdaptionButton = uiradiobutton(app.stimButtonGroup);
            app.selfAdaptionButton.Text = '自适应';
            app.imageReconstructionModeButtonGroup = uibuttongroup(app.parameterSetPanel);
            app.imageReconstructionModeButtonGroup.Title = '成像方式';
            app.absoluteButton = uiradiobutton(app.imageReconstructionModeButtonGroup);
            app.absoluteButton.Text = '静态';
            app.differenceButton = uiradiobutton(app.imageReconstructionModeButtonGroup);
            app.differenceButton.Text = '动态';
            app.differenceButton.Value = true;
            app.imageReconstructionAlgorithmButtonGroup = uibuttongroup(app.parameterSetPanel);
            app.imageReconstructionAlgorithmButtonGroup.Title = '成像算法';
            app.Button_9 = uiradiobutton(app.imageReconstructionAlgorithmButtonGroup);
            app.Button_9.Text = '反投影';
            app.Button_9.Value = true;
            app.Button_10 = uiradiobutton(app.imageReconstructionAlgorithmButtonGroup);
            app.Button_10.Text = '高斯-牛顿';
            app.Button_11 = uiradiobutton(app.imageReconstructionAlgorithmButtonGroup);
            app.Button_11.Text = '拉夫逊';
            app.StartPauseButton = uibutton(app.Imaging, 'state');
            app.StartPauseButton.ValueChangedFcn = createCallbackFcn(app, @StartPauseButtonValueChanged, true);
            app.StartPauseButton.Icon = 'start.png';
            app.StartPauseButton.Text = '';
            app.calibrationButton = uibutton(app.Imaging, 'push');
            app.calibrationButton.ButtonPushedFcn = createCallbackFcn(app, @calibrationButtonPushed, true);
            app.calibrationButton.Text = '';
            app.openSerialButton = uibutton(app.Imaging, 'push');
            app.openSerialButton.ButtonPushedFcn = createCallbackFcn(app, @openSerialButtonPushed, true);
            app.openSerialButton.Text = '打开串口';
            app.Lamp = uilamp(app.Imaging);
            app.TextArea = uitextarea(app.Imaging);
            app.logLabel = uilabel(app.Imaging);
            app.logLabel.Text = '123';
            app.realTimeElectrodesVoltageUIAxes = uiaxes(app.Imaging);
            title(app.realTimeElectrodesVoltageUIAxes, '实时数据')
            xlabel(app.realTimeElectrodesVoltageUIAxes, '编号')
            ylabel(app.realTimeElectrodesVoltageUIAxes, '电压/mV')
            app.Label = uilabel(app.Imaging);
            app.Label.Text = '端口号';
            app.serialComDropDown = uidropdown(app.Imaging);
            app.serialComDropDown.Items = {'COM1'};
            app.serialComDropDown.Value = 'COM1';
            app.msLabel = uilabel(app.Imaging);
            app.msLabel.Text = '算法耗时/ms：';
            app.algorithmTimeConsumingEditField = uieditfield(app.Imaging, 'numeric');
            app.algorithmTimeConsumingEditField.ValueDisplayFormat = '%.0f';
            app.algorithmTimeConsumingEditField.Editable = 'off';
            app.Label_3 = uilabel(app.Imaging);
            app.Label_3.Text = '同步：';
            app.readyLamp = uilamp(app.Imaging);
            app.statusLamp = uilamp(app.Imaging);
            app.Testing = uitab(app.TabGroup);
            app.Testing.Title = '测试';
            app.testGetHomogeneousDataButton = uibutton(app.Testing, 'push');
            app.testGetHomogeneousDataButton.ButtonPushedFcn = createCallbackFcn(app, @testGetHomogeneousDataButtonPushed, true);
            app.testGetHomogeneousDataButton.Text = '获取均匀场数据';
            app.testGetDataOneceButton = uibutton(app.Testing, 'push');
            app.testGetDataOneceButton.Text = '单次采集';
            app.Label_2 = uilabel(app.Testing);
            app.Label_2.Text = '激励电极';
            app.testStimElectrodesChooseDropDown = uidropdown(app.Testing);
            app.testStimElectrodesChooseDropDown.Items = {'1-2', '2-3', '3-4', '4-5', '5-6', '6-7', '7-8', '8-9', '9-10', '10-11', '11-12', '12-13', '13-14', '14-15', '15-16'};
            app.testStimElectrodesChooseDropDown.Value = '1-2';
            app.DropDown_2Label = uilabel(app.Testing);
            app.DropDown_2Label.Text = '测量电极';
            app.testMeasureElectrodesChooseDropDown = uidropdown(app.Testing);
            app.testMeasureElectrodesChooseDropDown.Items = {'1-2', '2-3', '3-4', '4-5', '5-6', '6-7', '7-8', '8-9', '9-10', '10-11', '11-12', '12-13', '13-14', '14-15', '15-16'};
            app.testMeasureElectrodesChooseDropDown.Value = '1-2';
            app.testStimingButton = uibutton(app.Testing, 'push');
            app.testStimingButton.Text = '激励';
            app.testMeasuringButton = uibutton(app.Testing, 'push');
            app.testMeasuringButton.Text = '测量';
            app.testDrawVoltageCurvesButton = uibutton(app.Testing, 'push');
            app.testDrawVoltageCurvesButton.Text = '绘图';
            app.testLogTextArea = uitextarea(app.Testing);
            app.e1EditFieldLabel = uilabel(app.Testing);
            app.e1EditFieldLabel.Text = 'e1';
            app.e1EditField = uieditfield(app.Testing, 'numeric');
            app.e1EditField.ValueDisplayFormat = '%.3f';
            app.e1EditField.Editable = 'off';
            app.e6EditFieldLabel = uilabel(app.Testing);
            app.e6EditFieldLabel.Text = 'e6';
            app.e6EditField = uieditfield(app.Testing, 'numeric');
            app.e6EditField.ValueDisplayFormat = '%.3f';
            app.e6EditField.Editable = 'off';
            app.e2EditFieldLabel = uilabel(app.Testing);
            app.e2EditFieldLabel.Text = 'e2';
            app.e2EditField = uieditfield(app.Testing, 'numeric');
            app.e2EditField.ValueDisplayFormat = '%.3f';
            app.e2EditField.Editable = 'off';
            app.e7EditFieldLabel = uilabel(app.Testing);
            app.e7EditFieldLabel.Text = 'e7';
            app.e7EditField = uieditfield(app.Testing, 'numeric');
            app.e7EditField.ValueDisplayFormat = '%.3f';
            app.e7EditField.Editable = 'off';
            app.e3EditFieldLabel = uilabel(app.Testing);
            app.e3EditFieldLabel.Text = 'e3';
            app.e3EditField = uieditfield(app.Testing, 'numeric');
            app.e3EditField.ValueDisplayFormat = '%.3f';
            app.e3EditField.Editable = 'off';
            app.e8EditFieldLabel = uilabel(app.Testing);
            app.e8EditFieldLabel.Text = 'e8';
            app.e8EditField = uieditfield(app.Testing, 'numeric');
            app.e8EditField.ValueDisplayFormat = '%.3f';
            app.e8EditField.Editable = 'off';
            app.e4EditFieldLabel = uilabel(app.Testing);
            app.e4EditFieldLabel.Text = 'e4';
            app.e4EditField = uieditfield(app.Testing, 'numeric');
            app.e4EditField.ValueDisplayFormat = '%.3f';
            app.e4EditField.Editable = 'off';
            app.e9EditFieldLabel = uilabel(app.Testing);
            app.e9EditFieldLabel.Text = 'e9';
            app.e9EditField = uieditfield(app.Testing, 'numeric');
            app.e9EditField.ValueDisplayFormat = '%.3f';
            app.e9EditField.Editable = 'off';
            app.e5EditFieldLabel = uilabel(app.Testing);
            app.e5EditFieldLabel.Text = 'e5';
            app.e5EditField = uieditfield(app.Testing, 'numeric');
            app.e5EditField.ValueDisplayFormat = '%.3f';
            app.e5EditField.Editable = 'off';
            app.e10EditFieldLabel = uilabel(app.Testing);
            app.e10EditFieldLabel.Text = 'e10';
            app.e10EditField = uieditfield(app.Testing, 'numeric');
            app.e10EditField.ValueDisplayFormat = '%.3f';
            app.e10EditField.Editable = 'off';
            app.e11EditFieldLabel = uilabel(app.Testing);
            app.e11EditFieldLabel.Text = 'e11';
            app.e11EditField = uieditfield(app.Testing, 'numeric');
            app.e11EditField.ValueDisplayFormat = '%.3f';
            app.e11EditField.Editable = 'off';
            app.e12EditFieldLabel = uilabel(app.Testing);
            app.e12EditFieldLabel.Text = 'e12';
            app.e12EditField = uieditfield(app.Testing, 'numeric');
            app.e12EditField.ValueDisplayFormat = '%.3f';
            app.e12EditField.Editable = 'off';
            app.e13EditFieldLabel = uilabel(app.Testing);
            app.e13EditFieldLabel.Text = 'e13';
            app.e13EditField = uieditfield(app.Testing, 'numeric');
            app.e13EditField.ValueDisplayFormat = '%.3f';
            app.e13EditField.Editable = 'off';
            app.e14EditFieldLabel = uilabel(app.Testing);
            app.e14EditFieldLabel.Text = 'e14';
            app.e14EditField = uieditfield(app.Testing, 'numeric');
            app.e14EditField.ValueDisplayFormat = '%.3f';
            app.e14EditField.Editable = 'off';
            app.e15EditFieldLabel = uilabel(app.Testing);
            app.e15EditFieldLabel.Text = 'e15';
            app.e15EditField = uieditfield(app.Testing, 'numeric');
            app.e15EditField.ValueDisplayFormat = '%.3f';
            app.e15EditField.Editable = 'off';
            app.e16EditFieldLabel = uilabel(app.Testing);
            app.e16EditFieldLabel.Text = 'e16';
            app.e16EditField = uieditfield(app.Testing, 'numeric');
            app.e16EditField.ValueDisplayFormat = '%.3f';
            app.e16EditField.Editable = 'off';
            app.simulationTab = uitab(app.TabGroup);
            app.simulationTab.Title = '仿真';
            app.simulationButton = uibutton(app.simulationTab, 'push');
            app.simulationButton.ButtonPushedFcn = createCallbackFcn(app, @simulationButtonPushed, true);
            app.simulationButton.Icon = 'simulation.png';
            app.simulationButton.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EIT

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EITSystemv10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EITSystemv10UIFigure)
        end
    end
end